classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        initButton                      matlab.ui.control.Button
        workspaceButton                 matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        settingsTab                     matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        SensorsTab                      matlab.ui.container.Tab
        SensorsUITable                  matlab.ui.control.Table
        MCUTab                          matlab.ui.container.Tab
        MCUUITable                      matlab.ui.control.Table
        TransmissionTab                 matlab.ui.container.Tab
        TransmissionUITable             matlab.ui.control.Table
        modulesimulationsTab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SensorsTab_2                    matlab.ui.container.Tab
        SensorsimulationButton          matlab.ui.control.Button
        disp_sensorButton               matlab.ui.control.Button
        maximumnumberofmeasurementsperdayEditFieldLabel  matlab.ui.control.Label
        nmax                            matlab.ui.control.NumericEditField
        uitleg                          matlab.ui.control.Label
        SavePlotsLabel                  matlab.ui.control.Label
        PlotSaveSensorSwitch            matlab.ui.control.Switch
        EnableplottingLabel_2           matlab.ui.control.Label
        PlotSensorSwitch                matlab.ui.control.Switch
        SaveResultsSwitch_2Label        matlab.ui.control.Label
        SaveSensorResultsSwitch         matlab.ui.control.Switch
        MCUTab_2                        matlab.ui.container.Tab
        TransmissionTab_2               matlab.ui.container.Tab
        TotalSystemTab                  matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        SingleSimulationsTab            matlab.ui.container.Tab
        TotalsimulationButton           matlab.ui.control.Button
        disp_totalButton                matlab.ui.control.Button
        EnableplottingLabel             matlab.ui.control.Label
        PlotTotalSwitch                 matlab.ui.control.Switch
        timehEditFieldLabel             matlab.ui.control.Label
        timeEditField                   matlab.ui.control.NumericEditField
        energyJEditFieldLabel           matlab.ui.control.Label
        energyEditField                 matlab.ui.control.NumericEditField
        SaveResultsSwitchLabel          matlab.ui.control.Label
        SaveResultsSwitch               matlab.ui.control.Switch
        Tot_RefreshButton               matlab.ui.control.Button
        UITableInterval                 matlab.ui.control.Table
        ReportButton                    matlab.ui.control.Button
        SaveplotsLabel                  matlab.ui.control.Label
        PlotSaveTotalSwitch             matlab.ui.control.Switch
        LossesLabel                     matlab.ui.control.Label
        LossesTotalEditField            matlab.ui.control.NumericEditField
        ReportdoesuseolderresultsLabel  matlab.ui.control.Label
        CompareSimulationsTab           matlab.ui.container.Tab
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        RefreshFilesButton              matlab.ui.control.Button
        runningLampLabel                matlab.ui.control.Label
        runningLamp                     matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        init %initmeuk
        Results %results struct of all results of calc_ functions
        list %geeft lbox
        Selected %structure with .Sensors for selected sensors (and .MCU etc) .Sensors.Data .Sensors.Name .MCU.Data .MCU.Value
        SimulationNumber
        Save % structure with .Sensors containing true/false if plot need to be saved.
        Losses % used in disp_totalenergy (app.Losses.Total), is a factor of how much losses their are in the system
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.runningLamp.Color = 'r';
            close all;
            
            %[Batteries] = init_battery();
            [Sensors] = init_sensors();
            [MCU] = init_mcu();
            [Transmission] = init_transmission();
            
            app.init.Sensors = Sensors;
            app.init.MCU = MCU;
            app.init.Transmission = Transmission;
            
            checked = false(size(Sensors.Name,2),1);
            interval = Sensors.Data(3,:).'/24*3600; %converts default measurement into interval.
            parametersvalues = Sensors.Data(4:end,:).';
            sensorname2 = Sensors.Name.';
            cellarray = [ checked, sensorname2, interval, parametersvalues];
            app.SensorsUITable.ColumnName = ["Selected" app.init.Sensors.Parameters];
            app.SensorsUITable.ColumnEditable = [true false false true true true true true true];
            %app.SensorsUITable.ColumnEditable(2) = false;%zorgt er voor dat alleen interval colom aangepast kan worden
            app.SensorsUITable.ColumnFormat = {'logical',[]};
            app.SensorsUITable.Data = [cellarray];
            
            checked = false(size(MCU.Name,2),1);
            interval = MCU.Data(2,:).'/24*3600; %converts default measurement into interval.
            parametersvalues = MCU.Data(3:end,:).';
            MCUname2 = MCU.Name.';
            cellarray = [checked, MCUname2, interval, parametersvalues];
            app.MCUUITable.ColumnName = ["Selected" app.init.MCU.Parameters];
            app.MCUUITable.ColumnEditable = [true false true true true true true true];
            %app.MCUUITable.ColumnEditable(4) = true;%zorgt er voor dat alleen interval colom aangepast kan worden
            app.MCUUITable.ColumnFormat = {'logical',[]};
            app.MCUUITable.Data = [cellarray];
            
            checked = false(size(Transmission.Name,2),1);
            interval = Transmission.Data(2,:).'/24*3600; %converts default measurement into interval.
            parametersvalues = Transmission.Data(3:end,:).';
            Transmissionname2 = Transmission.Name.';
            cellarray = [ checked, Transmissionname2, interval, parametersvalues];
            app.TransmissionUITable.ColumnName = ["Selected" app.init.Transmission.Parameters];
            app.TransmissionUITable.ColumnEditable = [true false true true true true true true true true];
            %app.TransmissionUITable.ColumnEditable(4) = true;%zorgt er voor dat alleen interval colom aangepast kan worden
            app.TransmissionUITable.ColumnFormat = {'logical',[]};
            app.TransmissionUITable.Data = [cellarray];
            
            
            app.Selected.Sensors.Name = strings(0);
            app.Selected.Sensors.Data = zeros(1);
            app.Selected.MCU.Name = strings(0);
            app.Selected.MCU.Data = zeros(1);
            app.Selected.Transmission.Name = strings(0);
            app.Selected.Transmission.Data = zeros(1);
            app.SimulationNumber.Total = 1;
            app.SimulationNumber.Sensors = 1;
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: initButton
        function initButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            close all;
            [Sensors] = init_sensors();
            [MCU] = init_mcu();
            [Transmission] = init_transmission();
            
            app.init.T_alive = T_alive;
            app.init.E_available = E_available;
            app.init.Sensors = Sensors;
            app.init.MCU = MCU;
            app.init.Transmission = Transmission;
            app.runningLamp.Color = 'g';
            
        end

        % Button pushed function: SensorsimulationButton
        function SensorsimulationButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            N_Max = app.nmax.Value;
            [app.Results.Sensor.Data] = calc_sensors(app.Selected.Sensors.Data , N_Max);
            app.Results.Sensor.Name = app.Selected.Sensors.Name;
            
            
            if isequal(app.PlotSensorSwitch.Value, 'On')
                disp_sensors(app.Results.Sensor.Data,app.Results.Sensor.Name, app.Save.Sensor);
            end
            if isequal( app.SaveSensorResultsSwitch.Value, 'On' )
                temp = app.Results.Sensor;
                filename = strcat('calc_sensorV', num2str(app.SimulationNumber.Sensors), '.mat');
                fullfilename = fullfile('appsave', filename);
                save(fullfilename, 'temp');
            end
            app.SimulationNumber.Sensors = app.SimulationNumber.Sensors +1;
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: TotalsimulationButton
        function TotalsimulationButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            T_Max = app.timeEditField.Value*3600;
            E_Max = app.energyEditField.Value*1000;
            S_Sensors = app.Selected.Sensors.Data;
            S_MCU = app.Selected.MCU.Data(:,1);
            S_Com = app.Selected.Transmission.Data(:,1);
            S_names = [app.Selected.Sensors.Name(1,:) app.init.MCU.Name(1,1) app.init.Transmission.Name(1,1) "Total"];
            I_Array = app.UITableInterval.Data(app.UITableInterval.Data(:,1)~=0,:);%remove rows with only zeros I_Array
            I_Array(:,1) = I_Array(:,1)*3600;
            [app.Results.Total] = calc_totalenergy(S_Sensors, S_MCU, S_Com, I_Array, T_Max, E_Max);
            app.Results.Total.Name = S_names;
            app.runningLamp.Color = 'r';
            
            if isequal(app.PlotTotalSwitch.Value, 'On')
                disp_totalenergy(app.Results.Total,app.Losses.Total, app.Save.Total);
            end
            app.runningLamp.Color = 'r';
            
            if isequal( app.SaveResultsSwitch.Value, 'On' )
                temp = app.Results.Total;
                filename = strcat('calc_totalV', num2str(app.SimulationNumber.Total), '.mat');
                fullfilename = fullfile('appsave', filename);
                save(fullfilename, 'temp');
            end
            
            app.SimulationNumber.Total = app.SimulationNumber.Total +1;
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: disp_totalButton
        function disp_totalButtonPushed(app, event)

            disp_totalenergy(app.Results.Total,app.Losses.Total, app.Save.Total);
            
        end

        % Button pushed function: disp_sensorButton
        function disp_sensorButtonPushed(app, event)

            disp_sensors(app.Results.Sensor.Data,app.Results.Sensor.Name, app.Save.Sensor);
        end

        % Button pushed function: workspaceButton
        function workspaceButtonPushed(app, event)
            
            
            test = 0;
        end

        % Cell edit callback: SensorsUITable
        function SensorsUITableCellEdit(app, event)
            indices = event.Indices;
            newData = str2double(event.NewData);
            %alle aanpassingen in de tabel worden opgeslagen in
            %app.SensorsUITable.Data, als check box aangepast word dan ook
            %aangepast in SelectedItems.
            %app.SelectedSensors = app.init.Sensors.Data(:,app.SensorsUITable.Data(:,1)=='1');
            app.Selected.Sensors.Data = app.init.Sensors.Data(:,app.SensorsUITable.Data(:,1)=='1');
            app.Selected.Sensors.Name = app.init.Sensors.Name(:,app.SensorsUITable.Data(:,1)=='1');
            
        end

        % Button pushed function: Tot_RefreshButton
        function Tot_RefreshButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            stop = 0;
            if isempty(app.Selected.Sensors.Name)
                fig = uifigure();
                uialert(fig,'please select at least 1 sensor module ','Selection Error');
                stop = 1;
            elseif isempty(app.Selected.MCU.Name)
                fig = uifigure();
                uialert(fig,'please select 1 MCU module ','Selection Error');
                stop = 1;
            elseif isempty(app.Selected.Transmission.Name)
                fig = uifigure();
                uialert(fig,'please select 1 Transmission method ','Selection Error');
                stop = 1;
            elseif    size(app.Selected.MCU.Data,2) > 1
                fig = uifigure();
                uialert(fig,'please only select 1 MCU module','Selection Error');
                stop = 1;
            elseif size(app.Selected.Transmission.Data,2)>1
                fig = uifigure();
                uialert(fig,'please only select 1 Transmission method ','Selection Error');
                stop = 1;
            end
            
            if stop ==0
                I_test = [zeros(10,1) false(10,size(app.Selected.Sensors.Data,2)+2)];%added 2 for mcu and transmission
                str_logical = repelem({'logical'}, 1, size(app.Selected.Sensors.Data,2)+2);
                app.UITableInterval.ColumnFormat = [{'numeric'}, str_logical];
                S_names = [join(app.Selected.Sensors.Name,newline,1), app.Selected.MCU.Name, app.Selected.Transmission.Name];
                app.UITableInterval.ColumnName = ['Interval [h]' S_names];
                app.UITableInterval.ColumnEditable = true;
                app.UITableInterval.Data = [I_test];
            end
            
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            disp('started making report');
            run wordEnergy.mlx;
            disp('finished making report');
            app.runningLamp.Color = 'g';
            
        end

        % Cell edit callback: TransmissionUITable
        function TransmissionUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Selected.Transmission.Data = app.init.Transmission.Data(:,app.TransmissionUITable.Data(:,1)=='1');
            app.Selected.Transmission.Name = app.init.Transmission.Name(:,app.TransmissionUITable.Data(:,1)=='1');
        end

        % Cell edit callback: MCUUITable
        function MCUUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Selected.MCU.Data = app.init.MCU.Data(:,app.MCUUITable.Data(:,1)=='1');
            app.Selected.MCU.Name = app.init.MCU.Name(:,app.MCUUITable.Data(:,1)=='1');
        end

        % Button pushed function: RefreshFilesButton
        function RefreshFilesButtonPushed(app, event)
            
        end

        % Callback function
        function TypeofPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofPlotButtonGroup.SelectedObject;
            
            
            plot(app.UIAxes,app.Results.Total.time(:), app.Results.Total.E_Sub(:,end-1));
            app.UIAxes.Title.String = 'Total Energy usage';
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'E [mJ]';
            
        end

        % Callback function
        function loadtreeButtonPushed(app, event)
            %https://nl.mathworks.com/matlabcentral/answers/454602-app-designer-expanding-tree-using-a-struct-plotting-selected-data-edit-selected
            myStruct = app.Results;
            fNames1 = fieldnames(myStruct);
            for i=1:numel(fieldnames(myStruct))
                category(i)=uitreenode(app.Tree,'Text',string(fNames1(i)));
                fNames2 = fieldnames(myStruct.(string(fNames1(i))));
                
                for n=1:numel(fieldnames(myStruct.(string(fNames1(i)))))
                    uitreenode(category(i),'Text',string(fNames2(n)));
                end
            end
            
        end

        % Value changed function: SaveResultsSwitch
        function SaveResultsSwitchValueChanged(app, event)
            
        end

        % Value changed function: PlotSaveSensorSwitch
        function PlotSaveSensorSwitchValueChanged(app, event)
            app.Save.Sensor = app.PlotSaveSensorSwitch.Value;

        end

        % Value changed function: PlotSaveTotalSwitch
        function PlotSaveTotalSwitchValueChanged(app, event)
            if isequal(app.PlotSaveTotalSwitch.Value, 'On')
                app.Save.Total = true;
            else
                app.Save.Total = false;
            end
        end

        % Value changed function: timeEditField
        function timeEditFieldValueChanged(app, event)
            value = app.timeEditField.Value;
            
        end

        % Value changed function: LossesTotalEditField
        function LossesTotalEditFieldValueChanged(app, event)
            app.Losses.Total = app.LossesTotalEditField.Value/100;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.initButton = uibutton(app.UIFigure, 'push');
            app.initButton.ButtonPushedFcn = createCallbackFcn(app, @initButtonPushed, true);
            app.initButton.Text = 'init';
            app.workspaceButton = uibutton(app.UIFigure, 'push');
            app.workspaceButton.ButtonPushedFcn = createCallbackFcn(app, @workspaceButtonPushed, true);
            app.workspaceButton.Text = 'workspace';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.settingsTab = uitab(app.TabGroup2);
            app.settingsTab.Title = 'settings';
            app.TabGroup = uitabgroup(app.settingsTab);
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.SensorsUITable = uitable(app.SensorsTab);
            app.SensorsUITable.ColumnName = {''};
            app.SensorsUITable.RowName = {};
            app.SensorsUITable.CellEditCallback = createCallbackFcn(app, @SensorsUITableCellEdit, true);
            app.MCUTab = uitab(app.TabGroup);
            app.MCUTab.Title = 'MCU';
            app.MCUUITable = uitable(app.MCUTab);
            app.MCUUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MCUUITable.RowName = {};
            app.MCUUITable.CellEditCallback = createCallbackFcn(app, @MCUUITableCellEdit, true);
            app.TransmissionTab = uitab(app.TabGroup);
            app.TransmissionTab.Title = 'Transmission';
            app.TransmissionUITable = uitable(app.TransmissionTab);
            app.TransmissionUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TransmissionUITable.RowName = {};
            app.TransmissionUITable.CellEditCallback = createCallbackFcn(app, @TransmissionUITableCellEdit, true);
            app.modulesimulationsTab = uitab(app.TabGroup2);
            app.modulesimulationsTab.Title = 'module simulations';
            app.TabGroup3 = uitabgroup(app.modulesimulationsTab);
            app.SensorsTab_2 = uitab(app.TabGroup3);
            app.SensorsTab_2.Title = 'Sensors';
            app.SensorsimulationButton = uibutton(app.SensorsTab_2, 'push');
            app.SensorsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SensorsimulationButtonPushed, true);
            app.SensorsimulationButton.Text = {'Sensor simulation'; ''};
            app.disp_sensorButton = uibutton(app.SensorsTab_2, 'push');
            app.disp_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @disp_sensorButtonPushed, true);
            app.disp_sensorButton.Text = 'disp_sensor';
            app.maximumnumberofmeasurementsperdayEditFieldLabel = uilabel(app.SensorsTab_2);
            app.maximumnumberofmeasurementsperdayEditFieldLabel.Text = {'maximum number'; 'of measurements '; 'per day'};
            app.nmax = uieditfield(app.SensorsTab_2, 'numeric');
            app.uitleg = uilabel(app.SensorsTab_2);
            app.uitleg.Text = '(selected sensors in settings tab will be used)';
            app.SavePlotsLabel = uilabel(app.SensorsTab_2);
            app.SavePlotsLabel.Text = 'Save Plots';
            app.PlotSaveSensorSwitch = uiswitch(app.SensorsTab_2, 'slider');
            app.PlotSaveSensorSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSaveSensorSwitchValueChanged, true);
            app.PlotSaveSensorSwitch.Value = 'On';
            app.EnableplottingLabel_2 = uilabel(app.SensorsTab_2);
            app.EnableplottingLabel_2.Text = 'Enable plotting';
            app.PlotSensorSwitch = uiswitch(app.SensorsTab_2, 'slider');
            app.PlotSensorSwitch.Value = 'On';
            app.SaveResultsSwitch_2Label = uilabel(app.SensorsTab_2);
            app.SaveResultsSwitch_2Label.Text = 'Save Results';
            app.SaveSensorResultsSwitch = uiswitch(app.SensorsTab_2, 'slider');
            app.SaveSensorResultsSwitch.Value = 'On';
            app.MCUTab_2 = uitab(app.TabGroup3);
            app.MCUTab_2.Title = 'MCU';
            app.TransmissionTab_2 = uitab(app.TabGroup3);
            app.TransmissionTab_2.Title = 'Transmission';
            app.TotalSystemTab = uitab(app.TabGroup2);
            app.TotalSystemTab.Title = 'Total System';
            app.TabGroup4 = uitabgroup(app.TotalSystemTab);
            app.SingleSimulationsTab = uitab(app.TabGroup4);
            app.SingleSimulationsTab.Title = 'Single Simulations';
            app.TotalsimulationButton = uibutton(app.SingleSimulationsTab, 'push');
            app.TotalsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @TotalsimulationButtonPushed, true);
            app.TotalsimulationButton.Text = 'Total simulation';
            app.disp_totalButton = uibutton(app.SingleSimulationsTab, 'push');
            app.disp_totalButton.ButtonPushedFcn = createCallbackFcn(app, @disp_totalButtonPushed, true);
            app.disp_totalButton.Text = 'disp_total';
            app.EnableplottingLabel = uilabel(app.SingleSimulationsTab);
            app.EnableplottingLabel.Text = 'Enable plotting';
            app.PlotTotalSwitch = uiswitch(app.SingleSimulationsTab, 'slider');
            app.PlotTotalSwitch.Value = 'On';
            app.timehEditFieldLabel = uilabel(app.SingleSimulationsTab);
            app.timehEditFieldLabel.Text = 'time [h]';
            app.timeEditField = uieditfield(app.SingleSimulationsTab, 'numeric');
            app.timeEditField.ValueChangedFcn = createCallbackFcn(app, @timeEditFieldValueChanged, true);
            app.energyJEditFieldLabel = uilabel(app.SingleSimulationsTab);
            app.energyJEditFieldLabel.Text = 'energy [J]';
            app.energyEditField = uieditfield(app.SingleSimulationsTab, 'numeric');
            app.SaveResultsSwitchLabel = uilabel(app.SingleSimulationsTab);
            app.SaveResultsSwitchLabel.Text = 'Save Results';
            app.SaveResultsSwitch = uiswitch(app.SingleSimulationsTab, 'slider');
            app.SaveResultsSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveResultsSwitchValueChanged, true);
            app.SaveResultsSwitch.Value = 'On';
            app.Tot_RefreshButton = uibutton(app.SingleSimulationsTab, 'push');
            app.Tot_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Tot_RefreshButtonPushed, true);
            app.Tot_RefreshButton.Text = 'Refresh';
            app.UITableInterval = uitable(app.SingleSimulationsTab);
            app.UITableInterval.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableInterval.RowName = {};
            app.ReportButton = uibutton(app.SingleSimulationsTab, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.SaveplotsLabel = uilabel(app.SingleSimulationsTab);
            app.SaveplotsLabel.Text = 'Save plots';
            app.PlotSaveTotalSwitch = uiswitch(app.SingleSimulationsTab, 'slider');
            app.PlotSaveTotalSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSaveTotalSwitchValueChanged, true);
            app.PlotSaveTotalSwitch.Value = 'On';
            app.LossesLabel = uilabel(app.SingleSimulationsTab);
            app.LossesLabel.Text = 'Losses [%]';
            app.LossesTotalEditField = uieditfield(app.SingleSimulationsTab, 'numeric');
            app.LossesTotalEditField.ValueChangedFcn = createCallbackFcn(app, @LossesTotalEditFieldValueChanged, true);
            app.ReportdoesuseolderresultsLabel = uilabel(app.SingleSimulationsTab);
            app.ReportdoesuseolderresultsLabel.Text = {'Report does use older results and the '; 'generated file can be found in Report folder.'};
            app.CompareSimulationsTab = uitab(app.TabGroup4);
            app.CompareSimulationsTab.Title = 'Compare Simulations';
            app.ListBoxLabel = uilabel(app.CompareSimulationsTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.CompareSimulationsTab);
            app.RefreshFilesButton = uibutton(app.CompareSimulationsTab, 'push');
            app.RefreshFilesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFilesButtonPushed, true);
            app.RefreshFilesButton.Text = 'Refresh Files';
            app.runningLampLabel = uilabel(app.UIFigure);
            app.runningLampLabel.Text = 'running';
            app.runningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end